classdef transfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        nLabel       matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        SwitchLabel  matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        Label_4      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           sv = app.Switch.Value;
           if sv == '0'
               num = app.EditField.Value;
           else
               num = app.EditField_2.Value;
           end
           file = dir(fullfile('*.csv'));      %csvファイルの情報を全部読み取り
            filenames = {file.name};            %csvファイルの名前を取得
            [~,n] = size(filenames);            %csvファイルの個数を数える
            for i = 1 : n
             k = strcat(filenames(i));           %文字列に変換する
             output{i,1} = k{1,1};                 %名前を付けて
             output{i,2} = readmatrix(k{1,1 });     %データを入れる
             f = output{i,2};                      %data中のi行2列のデータを取出す
             f = f(:,3);                         %計測データの3列を取出す
                if sv == '0'
                    f = reshape(f,num,[]);                %行列変換（5行n列）
                else
                    f = reshape(f,[],num);              %行列変換（n行5列）
                end
             output{i,2} = f;
             outputname=append('op_',k{1,1},'.csv');
             writematrix(f,outputname);
             end
        clearvars -except output
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '実行';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '変換後';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 5;
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = '行　n列';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'あるいは';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Tag = 'n';
            app.EditField_2.Value = 5;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ｎ行';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'0', '1'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.Value = '0';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '列';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transfer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end