classdef APP_MP4toAVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StopButton                    matlab.ui.control.StateButton
        TabGroup                      matlab.ui.container.TabGroup
        UserManualTab                 matlab.ui.container.Tab
        Therehave4Class1AVIULabel_3   matlab.ui.control.Label
        OutputtypeTab                 matlab.ui.container.Tab
        Therehave4Class1AVIULabel     matlab.ui.control.Label
        OutputParameterPanel          matlab.ui.container.Panel
        OriginalFrameOrDownsamplingegLabel  matlab.ui.control.Label
        FrameRate                     matlab.ui.control.NumericEditField
        Frame_RateEditField_2Label    matlab.ui.control.Label
        fpsLabel                      matlab.ui.control.Label
        OutputType                    matlab.ui.control.DropDown
        Output_TypeDropDownLabel      matlab.ui.control.Label
        Therehave4Class1AVIULabel_2   matlab.ui.control.Label
        BrStreamConverter_Ver11Label  matlab.ui.control.Label
        Framenumber                   matlab.ui.control.NumericEditField
        TotalFramenumberLabel         matlab.ui.control.Label
        Fnameout                      matlab.ui.control.EditField
        FilenameLabel                 matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        ProgressBarGauge              matlab.ui.control.LinearGauge
        ProgressBarGaugeLabel         matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function streamconverter(app,filename)
                       
        end
        
    end
    
    methods (Access = public)
        
        function results = Mov2Avi(StrMov, sAvi, varargin)
            N = size(StrMov,2);
            % Prepare the new file.
            vidObj = VideoWriter(sAvi, varargin{:});
            open(vidObj);
            for k = 1:N
                currFrame = StrMov(k).cdata;
                cmap = StrMov(k).colormap;
                if ~isempty(cmap); currFrame = ind2rgb(currFrame, cmap); end
                writeVideo(vidObj,currFrame);
            end
            % Close the file.
            close(vidObj);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.StopButton.Value=0;
            % Display uigetfile dialog
            filterspec = {'*.mp4;*.mov','MP4 File'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            if (ischar(p))
               fname = fullfile(p,f);
               app.Fnameout.Value=fname;
               reader = VideoReader(fname);
               app.Framenumber.Value=reader.NumberOfFrame;
               app.FrameRate.Value = reader.FrameRate;
               app.StatusLamp.Color='1.00,0.00,0.00';
               imagesc(app.ImageAxes,read(reader,1));
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.StopButton.Value=0;
            outputname = [app.Fnameout.Value,'_',app.OutputType.Value];
            type = app.OutputType.Value;
            if strcmp(type,'GS-MJPEG')
                writer = VideoWriter(outputname, 'Motion JPEG AVI');
            else
                writer = VideoWriter(outputname, type);
            end

            % Uncompressed AVI;Grayscale AVI'
            writer.FrameRate = round(app.FrameRate.Value);
            %writer.FrameCount = 3;
            reader = VideoReader(app.Fnameout.Value);
            nFrame = reader.NumberOfFrame;
            a =  round(reader.FrameRate) / writer.FrameRate;
            app.ProgressBarGauge.Value = 1;
            times = 1;


            open(writer);
            
            for frame = 1:nFrame
                if (app.StopButton.Value == 1)
                    break;
                end
                if mod(frame,a) == 0
                %only read part of frame. (number = original fps / target fps = 24 / 8 =3)
                    img = read(reader,frame);
                    
                    %%%%-GrayScal Option-%%%%%%%
                    if strcmp(type,'Grayscale AVI') || strcmp(type,'GS-MJPEG')
                        img_gray = rgb2gray(img);
                        imagesc(app.ImageAxes,img_gray);
                        writeVideo(writer,img_gray);
                    else
                        imagesc(app.ImageAxes,img);
                        writeVideo(writer,img);
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                end
                times = times + 1 ;
               app.ProgressBarGauge.Value = times*100/nFrame;
               app.StatusLamp.Color='0.00,1.00,1.00';
            end
            close(writer);
            
            app.StatusLamp.Color='0.00,1.00,0.00';
            
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            % Stop the function
            %app.StopButton.Value=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '1. Load MP4';
            app.ProgressBarGaugeLabel = uilabel(app.UIFigure);
            app.ProgressBarGaugeLabel.Text = 'Progress Bar';
            app.ProgressBarGauge = uigauge(app.UIFigure, 'linear');
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name: ';
            app.Fnameout = uieditfield(app.UIFigure, 'text');
            app.TotalFramenumberLabel = uilabel(app.UIFigure);
            app.TotalFramenumberLabel.Text = 'Total Frame number';
            app.Framenumber = uieditfield(app.UIFigure, 'numeric');
            app.BrStreamConverter_Ver11Label = uilabel(app.UIFigure);
            app.BrStreamConverter_Ver11Label.Text = 'Br-StreamConverter_Ver1.1';
            app.Therehave4Class1AVIULabel_2 = uilabel(app.UIFigure);
            app.Therehave4Class1AVIULabel_2.Text = 'Copyright @2022 OMU_Bridge Engineering Lab.,  All rights reservet';
            app.OutputParameterPanel = uipanel(app.UIFigure);
            app.OutputParameterPanel.Title = 'Output Parameter';
            app.Output_TypeDropDownLabel = uilabel(app.OutputParameterPanel);
            app.Output_TypeDropDownLabel.Text = 'Output_Type';
            app.OutputType = uidropdown(app.OutputParameterPanel);
            app.OutputType.Items = {'U-AVI', 'U-AVI+Grayscal', 'MJPEG', 'MJPEG+GrayS'};
            app.OutputType.ItemsData = {'Uncompressed AVI', 'Grayscale AVI', 'Motion JPEG AVI', 'GS-MJPEG'};
            app.OutputType.Value = 'Uncompressed AVI';
            app.fpsLabel = uilabel(app.OutputParameterPanel);
            app.fpsLabel.Text = 'fps';
            app.Frame_RateEditField_2Label = uilabel(app.OutputParameterPanel);
            app.Frame_RateEditField_2Label.Text = 'Frame_Rate';
            app.FrameRate = uieditfield(app.OutputParameterPanel, 'numeric');
            app.OriginalFrameOrDownsamplingegLabel = uilabel(app.OutputParameterPanel);
            app.OriginalFrameOrDownsamplingegLabel.Text = {'*Original Frame '; '  Or Down sampling'; '  (If Original Frame is 24, '; '     outfile Recommend 8)'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UserManualTab = uitab(app.TabGroup);
            app.UserManualTab.Title = 'User Manual';
            app.Therehave4Class1AVIULabel_3 = uilabel(app.UserManualTab);
            app.Therehave4Class1AVIULabel_3.Text = {' 1. Load MP4.'; ' 2. Select Output type'; ' 3. Define the Frame Rate of outputfile'; ' 4. Execute'};
            app.OutputtypeTab = uitab(app.TabGroup);
            app.OutputtypeTab.Title = 'Output type ';
            app.Therehave4Class1AVIULabel = uilabel(app.OutputtypeTab);
            app.Therehave4Class1AVIULabel.Text = {' 1. U-AVI: 非圧縮+AVI (Uncompressed AVI)'; ' 2. U-AVI + Grayscale: グレースケール+非圧縮AVI (U-AVI + Grayscale)'; ' 3. MJPEG: Motion JPEG AVI'; ' 4. MJPEG+GrayS: Grayscal + MJPEG AVI'};
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_MP4toAVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end